classdef G11_DSP_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        VisualAnalysisButtonGroup       matlab.ui.container.ButtonGroup
        FrequencyDomainButton           matlab.ui.control.ToggleButton
        TimeDomainButton                matlab.ui.control.ToggleButton
        SelectButton                    matlab.ui.control.Button
        Label_5                         matlab.ui.control.Label
        FilePathEditField               matlab.ui.control.EditField
        TabGroup                        matlab.ui.container.TabGroup
        AddNoiseTab                     matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
        NoiseGainSlider                 matlab.ui.control.Slider
        Label_2                         matlab.ui.control.Label
        NoiseDistributionDropDown       matlab.ui.control.DropDown
        ProcessandAnalyseButton         matlab.ui.control.Button
        EditField2                      matlab.ui.control.NumericEditField
        FrequencyHzLabel                matlab.ui.control.Label
        FilterTab                       matlab.ui.container.Tab
        Label_7                         matlab.ui.control.Label
        WindowFunctionDropDown          matlab.ui.control.DropDown
        Label_8                         matlab.ui.control.Label
        OrderEditField                  matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        EditField3                      matlab.ui.control.NumericEditField
        LowerFreqLimitLabel             matlab.ui.control.Label
        EditField4                      matlab.ui.control.NumericEditField
        UpperFreqLimitLabel             matlab.ui.control.Label
        ProcessandAnalyseButton_2       matlab.ui.control.Button
        NoiseSuppressionTab             matlab.ui.container.Tab
        ProcessandAnalyseButton_3       matlab.ui.control.Button
        SmoothFactorEditFieldLabel      matlab.ui.control.Label
        SmoothFactorEditField           matlab.ui.control.EditField
        VADThresholdEditFieldLabel      matlab.ui.control.Label
        VADThresholdEditField           matlab.ui.control.EditField
        FrameDurationEditFieldLabel     matlab.ui.control.Label
        FrameDurationEditField          matlab.ui.control.EditField
        ReverberationTab                matlab.ui.container.Tab
        ProcessandAnalyseButton_4       matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        Reverb_GainSlider               matlab.ui.control.Slider
        Label_4                         matlab.ui.control.Label
        Reverb_DelaySlider              matlab.ui.control.Slider
        VoiceChangingTab                matlab.ui.container.Tab
        ProcessandAnalyseButton_5       matlab.ui.control.Button
        Label_12                        matlab.ui.control.Label
        FundamentalFrequencyCoefficientSlider  matlab.ui.control.Slider
        SpeedModificationSliderLabel    matlab.ui.control.Label
        SpeedModificationSlider         matlab.ui.control.Slider
        WomanButton                     matlab.ui.control.Button
        ManButton                       matlab.ui.control.Button
        UpendButton                     matlab.ui.control.Button
        SpeechEmotionRecognitionTab     matlab.ui.container.Tab
        GeneratingaspectrogramButton    matlab.ui.control.Button
        SpeechemotionrecognitionresultsEditFieldLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        SpeechemotionrecognitionresultsEditField  matlab.ui.control.EditField
        TheprocessedsequenceisusedasthenewinputButton  matlab.ui.control.Button
        SavetheprocessedsequenceButton  matlab.ui.control.Button
        TakethefileasinputButton        matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        PlayButton_2                    matlab.ui.control.Button
        StopButton_2                    matlab.ui.control.Button
        StopButton_3                    matlab.ui.control.Button
        RecordingButton                 matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
    end

    
    properties (Access = private)

    end
    
    properties (Access = public)
%         Property2 % Description

        file="ttsmaker-file-2023-12-23-20-30-29.mp3";
        folder="D:\workplace\matlab\dsp_integrate_experiment\";
        y1;
        fs1;
        y2;

        ffty1;

        ffty2;

        recObj = audiorecorder;
    end
    
    methods (Access = private)
        
        function to_one(app)
            app.y1 = sum(app.y1,2)/2;
        end
        
        function [f, mag] = seq2f2mag(~,x,Fs)
            % 读取音频文件
            % 计算FFT并获取幅度谱
            N = length(x);
            n = pow2(nextpow2(N));  % transform length
            X = fft(x,n);
%             mag = 20*log10(abs(X(1:n/2+1)));
            mag = abs(X(1:n/2+1)).^2/n;
            % 创建频率向量
            f = Fs*(0:(n/2))/n;
        end
        
        function draw_output(app)
            if app.TimeDomainButton.Value
                plot(app.UIAxes_2, linspace(0,(length(app.y2)/app.fs1),length(app.y2)), app.y2 )
                xlabel(app.UIAxes_2, 't/s')
                ylabel(app.UIAxes_2, 'Amp.')
            elseif app.FrequencyDomainButton.Value
                [f,mag]=app.seq2f2mag(app.y2,app.fs1);
                plot(app.UIAxes_2, f,mag)
                xlabel(app.UIAxes_2, 'f/Hz')
                ylabel(app.UIAxes_2, 'Mag.')
            end
        end
        
        function draw_input(app)
            if app.TimeDomainButton.Value
                plot(app.UIAxes_3, linspace(0,(length(app.y1)/app.fs1),length(app.y1)), app.y1 )
                xlabel(app.UIAxes_3, 't/s')
                ylabel(app.UIAxes_3, 'Amp.')
            elseif app.FrequencyDomainButton.Value
                [f,mag]=app.seq2f2mag(app.y1,app.fs1);
                plot(app.UIAxes_3, f ,mag )
                xlabel(app.UIAxes_3, 'f/Hz')
                ylabel(app.UIAxes_3, 'Mag.')
            end
        end
    end
    

    methods (Access = private)

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            try
                [app.file,app.folder]=uigetfile('*.mp3;*.wav;*.m4a');
                app.FilePathEditField.Value=strcat(app.folder,app.file);
            catch 
                fprintf("Unselected")
            end

        end

        % Button pushed function: TakethefileasinputButton
        function TakethefileasinputButtonPushed(app, event)
            try
                [app.y1,app.fs1]=audioread(strcat(app.folder,app.file));
                app.to_one()
                app.draw_input()
            catch e
                e
                warndlg("Invalid input")
            end
        end

        % Button pushed function: ProcessandAnalyseButton
        function ProcessandAnalyseButtonPushed(app, event)
            try
                cata=app.NoiseDistributionDropDown.Value;    
                if cata=="Mean"
                    app.y2=rand(length(app.y1),1)*2*app.NoiseGainSlider.Value*max(app.y1)+app.y1;
                elseif cata=="Gaussian"
                    app.y2=randn(length(app.y1),1)*app.NoiseGainSlider.Value*max(app.y1)+app.y1;
                elseif cata=="Sine"
                    f=app.EditField2.Value;
                    app.y2=cos(2*pi*f*(1:length(app.y1))/app.fs1)'*app.NoiseGainSlider.Value*max(app.y1)+app.y1;
%                 else
%                     warndlg("未处理")
%                     return
                end
                
                app.draw_output();
               
            catch e
                e
                warndlg("未处理")
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if ~isempty(app.y1)
                sound(app.y1,app.fs1)
            end
        end

        % Value changed function: NoiseDistributionDropDown
        function NoiseDistributionDropDownValueChanged(app, event)
            value = app.NoiseDistributionDropDown.Value;
            if value=="Sine"
                app.EditField2.Enable=1;
            else
                app.EditField2.Enable=0;
            end
        end

        % Selection changed function: VisualAnalysisButtonGroup
        function VisualAnalysisButtonGroupSelectionChanged(app, event)
            if ~isempty(app.y1)
                app.draw_input()
            end

            if ~isempty(app.y2)
                app.draw_output()
            end

        end

        % Button pushed function: 
        % TheprocessedsequenceisusedasthenewinputButton
        function TheprocessedsequenceisusedasthenewinputButtonPushed(app, event)
            if ~isempty(app.y2)
                app.y1=app.y2;
                app.draw_input()
            end
        end

        % Button pushed function: ProcessandAnalyseButton_4
        function ProcessandAnalyseButton_4Pushed(app, event)
            if isempty(app.y1)
                return
            end
            if size(app.y1,1)<size(app.y1,2)
                app.y1=app.y1';
            end
            app.y2=[zeros(floor(app.Reverb_DelaySlider.Value*app.fs1),1);app.Reverb_GainSlider.Value.*app.y1]+...
                    [app.y1;zeros(floor(app.Reverb_DelaySlider.Value*app.fs1),1)];
            app.y2=app.y2(1:length(app.y1));
            app.draw_output();
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
            if ~isempty(app.y2)
                sound(app.y2,app.fs1)
            end
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            clear sound
        end

        % Button pushed function: StopButton_3
        function StopButton_3Pushed(app, event)
            clear sound
        end

        % Button pushed function: RecordingButton
        function RecordingButtonPushed(app, event)
            record(app.recObj);
            disp('Recording in progress now ...')
            app.Lamp.Color='r';
        end

        % Button pushed function: ProcessandAnalyseButton_2
        function ProcessandAnalyseButton_2Pushed(app, event)
            try
                windowname=app.WindowFunctionDropDown.Value;
                cata=app.TypeDropDown.Value;
                n=app.EditField2.Value;

                if windowname=="Hamming"
                    window=hamming(n+1);
                elseif windowname=="Blackman"
                    window=blackman(n+1);
                elseif windowname=="Rectangular"
                    window=ones(1,n+1);
                end

%                 user_filter=fir1()
                if cata=="Highpass"
                    ll=app.EditField3.Value/app.fs1*2;
                    user_filter=fir1(n,ll,"high",window);
                    app.y2=filter(user_filter,1,app.y1);

                elseif cata=="Lowpass"
                    ul=app.EditField4.Value/app.fs1*2;
                    user_filter=fir1(n,ul,"low",window);
                    app.y2=filter(user_filter,1,app.y1);

                elseif cata=="Bandpass"
                    ul=app.EditField4.Value/app.fs1*2;
                    ll=app.EditField3.Value/app.fs1*2;
                    user_filter=fir1(n,[ll ul],"bandpass",window);
                    app.y2=filter(user_filter,1,app.y1);

                elseif cata=="Bandstop"
                    ul=app.EditField4.Value/app.fs1*2;
                    ll=app.EditField3.Value/app.fs1*2;
                    user_filter=fir1(n,[ll ul],"stop",window);
                    app.y2=filter(user_filter,1,app.y1);

                else
                    warndlg("未处理")
                    return
                end
                
                app.draw_output();
               
            catch e
                e
                warndlg("未处理")
            end
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            cata = app.TypeDropDown.Value;

            if cata=="Highpass"
                app.EditField3.Enable=1;
                app.EditField4.Enable=0;
            elseif cata=="Lowpass"
                app.EditField3.Enable=0;
                app.EditField4.Enable=1;

            elseif cata=="Bandpass"
                app.EditField3.Enable=1;
                app.EditField4.Enable=1;

            elseif cata=="Bandstop"
                app.EditField3.Enable=1;
                app.EditField4.Enable=1;

            else
                warndlg("未处理")
                return
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            try
                stop(app.recObj)
                app.y1=getaudiodata(app.recObj);
                app.fs1=8000;
                app.draw_input()
                app.Lamp.Color='black';
            catch e
                e
            end
        end

        % Button pushed function: ProcessandAnalyseButton_5
        function ProcessandAnalyseButton_5Pushed(app, event)
            try
                slidernum=app.FundamentalFrequencyCoefficientSlider.Value;
                if slidernum<0
                    alpha=-5*slidernum;
                elseif slidernum>0
                    alpha=1/(1+5*slidernum);
                else
                    alpha=1;
                end

                app.y2=voice(app.y1,alpha);
                [q,p]=rat(app.SpeedModificationSlider.Value);
                app.y2=resample(app.y2,p,q);
                app.draw_output()
            catch e
                e
            end
        end

        % Button pushed function: SavetheprocessedsequenceButton
        function SavetheprocessedsequenceButtonPushed(app, event)
            try
                audiowrite(strcat("-1-",app.file,".wav"),app.y2,app.fs1)
            catch e
                e
            end
        end

        % Button pushed function: ProcessandAnalyseButton_3
        function ProcessandAnalyseButton_3Pushed(app, event)
            try
                if size(app.y1,1)<size(app.y1,2)
                    app.y1=app.y1';
                end
                mu=str2double(app.SmoothFactorEditField.Value);
                eta=str2double(app.VADThresholdEditField.Value);
                fr=str2double(app.FrameDurationEditField.Value);
                app.y2=wiener_as(app.y1,app.fs1,mu,eta,fr);
                app.draw_output()
            catch e
                e
            end
        end

        % Button pushed function: WomanButton
        function WomanButtonPushed(app, event)
            app.FundamentalFrequencyCoefficientSlider.Value = 0.17;
        end

        % Button pushed function: ManButton
        function ManButtonPushed(app, event)
            app.FundamentalFrequencyCoefficientSlider.Value = -0.23;
        end

        % Button pushed function: UpendButton
        function UpendButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'After Processing')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Before Processing')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.VisualAnalysisButtonGroup = uibuttongroup(app.UIFigure);
            app.VisualAnalysisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VisualAnalysisButtonGroupSelectionChanged, true);
            app.VisualAnalysisButtonGroup.Title = 'Visual Analysis';
            app.FrequencyDomainButton = uitogglebutton(app.VisualAnalysisButtonGroup);
            app.FrequencyDomainButton.Text = 'Frequency Domain';
            app.FrequencyDomainButton.Value = true;
            app.TimeDomainButton = uitogglebutton(app.VisualAnalysisButtonGroup);
            app.TimeDomainButton.Text = 'Time Domain';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.UIFigure, 'text');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AddNoiseTab = uitab(app.TabGroup);
            app.AddNoiseTab.Title = 'Add Noise';
            app.Label = uilabel(app.AddNoiseTab);
            app.Label.Text = 'Noise Gain';
            app.NoiseGainSlider = uislider(app.AddNoiseTab);
            app.NoiseGainSlider.Limits = [0 0.4];
            app.Label_2 = uilabel(app.AddNoiseTab);
            app.Label_2.Text = 'Noise Distribution';
            app.NoiseDistributionDropDown = uidropdown(app.AddNoiseTab);
            app.NoiseDistributionDropDown.Items = {'Mean', 'Gaussian', 'Sine'};
            app.NoiseDistributionDropDown.ValueChangedFcn = createCallbackFcn(app, @NoiseDistributionDropDownValueChanged, true);
            app.NoiseDistributionDropDown.Value = 'Mean';
            app.ProcessandAnalyseButton = uibutton(app.AddNoiseTab, 'push');
            app.ProcessandAnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessandAnalyseButtonPushed, true);
            app.ProcessandAnalyseButton.Text = 'Process and Analyse';
            app.EditField2 = uieditfield(app.AddNoiseTab, 'numeric');
            app.EditField2.Limits = [0 20000];
            app.EditField2.Enable = 'off';
            app.EditField2.Value = 1800;
            app.FrequencyHzLabel = uilabel(app.AddNoiseTab);
            app.FrequencyHzLabel.Text = 'Frequency(Hz)';
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.Label_7 = uilabel(app.FilterTab);
            app.Label_7.Text = 'Window Function';
            app.WindowFunctionDropDown = uidropdown(app.FilterTab);
            app.WindowFunctionDropDown.Items = {'Hamming', 'Blackman', 'Rectangular'};
            app.WindowFunctionDropDown.Value = 'Hamming';
            app.Label_8 = uilabel(app.FilterTab);
            app.Label_8.Text = 'Order';
            app.OrderEditField = uieditfield(app.FilterTab, 'numeric');
            app.OrderEditField.Value = 48;
            app.Label_9 = uilabel(app.FilterTab);
            app.Label_9.Text = 'Type';
            app.TypeDropDown = uidropdown(app.FilterTab);
            app.TypeDropDown.Items = {'Highpass', 'Lowpass', 'Bandpass', 'Bandstop'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Bandstop';
            app.EditField3 = uieditfield(app.FilterTab, 'numeric');
            app.EditField3.Limits = [0 10000];
            app.EditField3.Value = 400;
            app.LowerFreqLimitLabel = uilabel(app.FilterTab);
            app.LowerFreqLimitLabel.Text = 'Lower Freq. Limit';
            app.EditField4 = uieditfield(app.FilterTab, 'numeric');
            app.EditField4.Limits = [0 50000];
            app.EditField4.Value = 1000;
            app.UpperFreqLimitLabel = uilabel(app.FilterTab);
            app.UpperFreqLimitLabel.Text = 'Upper Freq. Limit';
            app.ProcessandAnalyseButton_2 = uibutton(app.FilterTab, 'push');
            app.ProcessandAnalyseButton_2.ButtonPushedFcn = createCallbackFcn(app, @ProcessandAnalyseButton_2Pushed, true);
            app.ProcessandAnalyseButton_2.Text = 'Process and Analyse';
            app.NoiseSuppressionTab = uitab(app.TabGroup);
            app.NoiseSuppressionTab.Title = 'Noise Suppression';
            app.ProcessandAnalyseButton_3 = uibutton(app.NoiseSuppressionTab, 'push');
            app.ProcessandAnalyseButton_3.ButtonPushedFcn = createCallbackFcn(app, @ProcessandAnalyseButton_3Pushed, true);
            app.ProcessandAnalyseButton_3.Text = 'Process and Analyse';
            app.SmoothFactorEditFieldLabel = uilabel(app.NoiseSuppressionTab);
            app.SmoothFactorEditFieldLabel.Text = 'Smooth Factor(μ)';
            app.SmoothFactorEditField = uieditfield(app.NoiseSuppressionTab, 'text');
            app.SmoothFactorEditField.Value = '0.98';
            app.VADThresholdEditFieldLabel = uilabel(app.NoiseSuppressionTab);
            app.VADThresholdEditFieldLabel.Text = 'VAD Threshold(η)';
            app.VADThresholdEditField = uieditfield(app.NoiseSuppressionTab, 'text');
            app.VADThresholdEditField.Value = '0.15';
            app.FrameDurationEditFieldLabel = uilabel(app.NoiseSuppressionTab);
            app.FrameDurationEditFieldLabel.Text = 'Frame Duration';
            app.FrameDurationEditField = uieditfield(app.NoiseSuppressionTab, 'text');
            app.FrameDurationEditField.Value = '20';
            app.ReverberationTab = uitab(app.TabGroup);
            app.ReverberationTab.Title = 'Reverberation';
            app.ProcessandAnalyseButton_4 = uibutton(app.ReverberationTab, 'push');
            app.ProcessandAnalyseButton_4.ButtonPushedFcn = createCallbackFcn(app, @ProcessandAnalyseButton_4Pushed, true);
            app.ProcessandAnalyseButton_4.Text = 'Process and Analyse';
            app.Label_3 = uilabel(app.ReverberationTab);
            app.Label_3.Text = 'Reverb_Gain';
            app.Reverb_GainSlider = uislider(app.ReverberationTab);
            app.Reverb_GainSlider.Limits = [0 1];
            app.Reverb_GainSlider.Value = 0.2;
            app.Label_4 = uilabel(app.ReverberationTab);
            app.Label_4.Text = 'Reverb_Delay';
            app.Reverb_DelaySlider = uislider(app.ReverberationTab);
            app.Reverb_DelaySlider.Limits = [0 1];
            app.Reverb_DelaySlider.Value = 0.2;
            app.VoiceChangingTab = uitab(app.TabGroup);
            app.VoiceChangingTab.Title = 'Voice Changing';
            app.ProcessandAnalyseButton_5 = uibutton(app.VoiceChangingTab, 'push');
            app.ProcessandAnalyseButton_5.ButtonPushedFcn = createCallbackFcn(app, @ProcessandAnalyseButton_5Pushed, true);
            app.ProcessandAnalyseButton_5.Text = 'Process and Analyse';
            app.Label_12 = uilabel(app.VoiceChangingTab);
            app.Label_12.Text = 'Fundamental Frequency Coefficient';
            app.FundamentalFrequencyCoefficientSlider = uislider(app.VoiceChangingTab);
            app.FundamentalFrequencyCoefficientSlider.Limits = [-1 1];
            app.FundamentalFrequencyCoefficientSlider.MajorTicks = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.FundamentalFrequencyCoefficientSlider.MinorTicks = [-1 -0.96 -0.92 -0.88 -0.84 -0.8 -0.76 -0.72 -0.68 -0.64 -0.6 -0.56 -0.52 -0.48 -0.44 -0.4 -0.36 -0.32 -0.28 -0.24 -0.2 -0.16 -0.12 -0.08 -0.04 0 0.04 0.0800000000000001 0.12 0.16 0.2 0.24 0.28 0.32 0.36 0.4 0.44 0.48 0.52 0.56 0.6 0.64 0.68 0.72 0.76 0.8 0.84 0.88 0.92 0.96 1];
            app.SpeedModificationSliderLabel = uilabel(app.VoiceChangingTab);
            app.SpeedModificationSliderLabel.Text = 'Speed Modification';
            app.SpeedModificationSlider = uislider(app.VoiceChangingTab);
            app.SpeedModificationSlider.Limits = [0 3];
            app.SpeedModificationSlider.MajorTicks = [0 0.25 0.5 0.75 1 1.25 1.5 1.75 2 2.25 2.5 2.75 3];
            app.SpeedModificationSlider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1 1.05 1.1 1.15 1.2 1.25 1.3 1.35 1.4 1.45 1.5 1.55 1.6 1.65 1.7 1.75 1.8 1.85 1.9 1.95 2 2.05 2.1 2.15 2.2 2.25 2.3 2.35 2.4 2.45 2.5 2.55 2.6 2.65 2.7 2.75 2.8 2.85 2.9 2.95 3];
            app.SpeedModificationSlider.Value = 1;
            app.WomanButton = uibutton(app.VoiceChangingTab, 'push');
            app.WomanButton.ButtonPushedFcn = createCallbackFcn(app, @WomanButtonPushed, true);
            app.WomanButton.Text = '→Woman';
            app.ManButton = uibutton(app.VoiceChangingTab, 'push');
            app.ManButton.ButtonPushedFcn = createCallbackFcn(app, @ManButtonPushed, true);
            app.ManButton.Text = '→Man';
            app.UpendButton = uibutton(app.VoiceChangingTab, 'push');
            app.UpendButton.ButtonPushedFcn = createCallbackFcn(app, @UpendButtonPushed, true);
            app.UpendButton.Text = 'Upend';
            app.SpeechEmotionRecognitionTab = uitab(app.TabGroup);
            app.SpeechEmotionRecognitionTab.Title = 'Speech Emotion Recognition';
            app.GeneratingaspectrogramButton = uibutton(app.SpeechEmotionRecognitionTab, 'push');
            app.GeneratingaspectrogramButton.Text = 'Generating a spectrogram';
            app.SpeechemotionrecognitionresultsEditFieldLabel = uilabel(app.SpeechEmotionRecognitionTab);
            app.SpeechemotionrecognitionresultsEditFieldLabel.Text = 'Speech emotion recognition results';
            app.SpeechemotionrecognitionresultsEditField = uieditfield(app.SpeechEmotionRecognitionTab, 'text');
            app.UIAxes = uiaxes(app.SpeechEmotionRecognitionTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TheprocessedsequenceisusedasthenewinputButton = uibutton(app.UIFigure, 'push');
            app.TheprocessedsequenceisusedasthenewinputButton.ButtonPushedFcn = createCallbackFcn(app, @TheprocessedsequenceisusedasthenewinputButtonPushed, true);
            app.TheprocessedsequenceisusedasthenewinputButton.Text = 'The processed sequence is used as the new input.';
            app.SavetheprocessedsequenceButton = uibutton(app.UIFigure, 'push');
            app.SavetheprocessedsequenceButton.ButtonPushedFcn = createCallbackFcn(app, @SavetheprocessedsequenceButtonPushed, true);
            app.SavetheprocessedsequenceButton.Text = 'Save the processed sequence.';
            app.TakethefileasinputButton = uibutton(app.UIFigure, 'push');
            app.TakethefileasinputButton.ButtonPushedFcn = createCallbackFcn(app, @TakethefileasinputButtonPushed, true);
            app.TakethefileasinputButton.Text = 'Take the file as input.';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PlayButton_2 = uibutton(app.UIFigure, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.StopButton_2 = uibutton(app.UIFigure, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.StopButton_3 = uibutton(app.UIFigure, 'push');
            app.StopButton_3.ButtonPushedFcn = createCallbackFcn(app, @StopButton_3Pushed, true);
            app.StopButton_3.Text = 'Stop';
            app.RecordingButton = uibutton(app.UIFigure, 'push');
            app.RecordingButton.ButtonPushedFcn = createCallbackFcn(app, @RecordingButtonPushed, true);
            app.RecordingButton.Text = 'Recording';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Lamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = G11_DSP_Project

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end